classdef BoolXRD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AcademicModeMenu                matlab.ui.container.Menu
        TrainingModeMenu                matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ImportRawXRDDataaswellCheckBox  matlab.ui.control.CheckBox
        PlaneOpacitySwitch              matlab.ui.control.Switch
        PlaneOpacitySwitchLabel         matlab.ui.control.Label
        LatticeSizecEditField           matlab.ui.control.NumericEditField
        LatticeSizecEditFieldLabel      matlab.ui.control.Label
        LatticeSizebEditField           matlab.ui.control.NumericEditField
        LatticeSizebEditFieldLabel      matlab.ui.control.Label
        LatticeSizeaEditField           matlab.ui.control.NumericEditField
        LatticeSizeaEditFieldLabel      matlab.ui.control.Label
        CrystalStructureDropDown        matlab.ui.control.DropDown
        CrystalStructureDropDownLabel   matlab.ui.control.Label
        AtomicSizeSlider                matlab.ui.control.Slider
        AtomicSizeSliderLabel           matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        Image                           matlab.ui.control.Image
        ReadPlotButton                  matlab.ui.control.StateButton
        RightPanel                      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        CommonMaterialsLabel            matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SampleMaterialsListBox          matlab.ui.control.ListBox
        OpacitySlider                   matlab.ui.control.Slider
        OpacitySliderLabel              matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)
        
        function results = recaller(app, old, hklSending)
            figure("Visible", "off");
            if (old == true)
                cla(app.UIAxes)
                a_size = app.LatticeSizeaEditField.Value;
                b_size = app.LatticeSizebEditField.Value;
                c_size = app.LatticeSizecEditField.Value;
                if (size(hklSending) == 0)
                    hkl = app.UITable.Data;
                else
                    hkl = hklSending{:,:};
                    app.UITable.Data = hkl;
                end
                count = 0;
                numDataSets = size(hkl, 1);
                plotColors = jet(numDataSets);
                for i = 1 : size(hkl, 1)
                    if hkl(i,1) == 0 || hkl(i,2) == 0 || hkl(i,3) == 0 
                        hkl(hkl==0) = 0.00001;
                    end
                
                    P1 = [1 / hkl(i, 1), 0, 0];
                    P2 = [0, 1 / hkl(i, 2), 0];
                    P3 = [0, 0, 1 / hkl(i, 3)];
                    
                    points=[P1' P2' P3']; % using the data given in the question
                    thisIndex = ceil(i);
                    thisColor = plotColors(thisIndex, :);
    
                    if app.PlaneOpacitySwitch.Value == "Slider"
                        opacity = app.OpacitySlider.Value / 100;
                    else
                        opacity = hkl(i,4) / 100;
                    end
                    fill3(app.UIAxes, a_size * points(1,:), b_size * points(2,:), c_size * points(3,:), thisColor, "FaceAlpha", opacity, "EdgeColor","black");
                    count = count + 1;
                    if count >= 1
                        hold(app.UIAxes, 'on');
                    end
                    box on;
                    xlabel(app.UIAxes,'X-Axis')
                    ylabel(app.UIAxes,'Y-Axis')
                    zlabel(app.UIAxes,'Z-Axis')
                    xlim(app.UIAxes,[0 a_size]) 
                    ylim(app.UIAxes,[0 b_size])
                    zlim(app.UIAxes,[0 c_size])
                    title(app.UIAxes, "XRD Planes and Crystal Structure")
                    lgd_data = app.UITable.Data;
                    lgd = legend(app.UIAxes, string(lgd_data(:, 1)) + string(lgd_data(:, 2)) + string(lgd_data(:, 3)));
                    title(lgd, "Miller Indices (hkl)")
                end
    
                if app.CrystalStructureDropDown.Value == "SC"
                   SC = [0, 0, 0; 0 0 1; 0 1 0; 0 1 1; 1 0 0; 1 0 1; 1 1 0; 1 1 1];
                   SC = SC .* [a_size b_size c_size];
                   scatter3(app.UIAxes,SC(:,1), SC(:,2), SC(:,3), app.AtomicSizeSlider.Value * 1000, "red", "filled", "MarkerFaceAlpha", 0.5); 
    
                elseif app.CrystalStructureDropDown.Value == "BCC"
                   BCC = [0,0,0; 0,0,1; 0,1,0; 1,0,0; 0,1,1; 1,1,0; 1,0,1; 1,1,1; 0.5, 0.5, 0.5];
                   BCC = BCC .* [a_size b_size c_size];
                   scatter3(app.UIAxes,BCC(:,1), BCC(:,2), BCC(:,3), app.AtomicSizeSlider.Value * 1000, "red", "filled", "MarkerFaceAlpha", 0.5); 
    
                elseif app.CrystalStructureDropDown.Value == "FCC"
                   FCC =[0,0,0; 0,0,1; 0,1,0; 1,0,0; 0,1,1; 1,1,0; 1,0,1; 1,1,1; 0.5, 0.5, 0; 0.5, 0, 0.5; 0, 0.5, 0.5; 0.5, 1, 0.5; 1, 0.5, 0.5; 0.5, 0.5, 1];
                   FCC = FCC .* [a_size b_size c_size];
                   scatter3(app.UIAxes,FCC(:,1), FCC(:,2), FCC(:,3), app.AtomicSizeSlider.Value * 1000, "red", "filled", "MarkerFaceAlpha", 0.5); 
                else
                    msgbox("You can also specify a crystal structure.", "Crystal Structure", "help")
                end
                hold(app.UIAxes, "off");
            else
                a_size = app.LatticeSizeaEditField.Value;
                b_size = app.LatticeSizebEditField.Value;
                c_size = app.LatticeSizecEditField.Value;

                uiwait(msgbox("Please select your .xlsx file with the hkl values.", "[BoolXRD] Upload hkl File", "modal"));
    
                [file, path] = uigetfile("*.xlsx");
                hkl_file = strcat(path, file);
                t = readmatrix(hkl_file);
                app.UITable.Data = t;
                
                if app.ImportRawXRDDataaswellCheckBox.Value == true
        
                    uiwait(msgbox("Please select your raw XRD Data with .xlsx extension.", "[BoolXRD] Upload Raw XRD Data", "modal"))
                    [file, path] = uigetfile("*.xlsx");
                    xrd_file = strcat(path, file);
                    xrd = readmatrix(xrd_file);
                    plot(app.UIAxes2, xrd(:,1), xrd(:,2));
                    hold(app.UIAxes2, "on");
                    title(app.UIAxes2, "XRD Data");
                    xlabel(app.UIAxes2, "Angle/2theta");
                    ylabel(app.UIAxes2, "Intensity");
                end
           
                hkl = app.UITable.Data;
                count = 0;
                numDataSets = size(hkl, 1);
                plotColors = jet(numDataSets);
                for i = 1 : size(hkl, 1)
                    if hkl(i,1) == 0 || hkl(i,2) == 0 || hkl(i,3) == 0 
                        hkl(hkl==0) = 0.00001;
                    end
                
                    P1 = [1 / hkl(i, 1), 0, 0];
                    P2 = [0, 1 / hkl(i, 2), 0];
                    P3 = [0, 0, 1 / hkl(i, 3)];
                    
                    points=[P1' P2' P3']; % using the data given in the question
                    thisIndex = ceil(i);
                    thisColor = plotColors(thisIndex, :);
    
                    if app.PlaneOpacitySwitch.Value == "Slider"
                        opacity = app.OpacitySlider.Value / 100;
                    else
                        opacity = hkl(i,4) / 100;
                    end
                    fill3(app.UIAxes, a_size * points(1,:), b_size * points(2,:), c_size * points(3,:), thisColor, "FaceAlpha", opacity, "EdgeColor","black");
                    count = count + 1;
                    if count >= 1
                        hold(app.UIAxes, 'on');
                    end
                    box on;
                    xlabel(app.UIAxes,'X-Axis')
                    ylabel(app.UIAxes,'Y-Axis')
                    zlabel(app.UIAxes,'Z-Axis')
                    xlim(app.UIAxes,[0 a_size]) 
                    ylim(app.UIAxes,[0 b_size])
                    zlim(app.UIAxes,[0 c_size])
                    title(app.UIAxes, "XRD Planes and Crystal Structure")
                    lgd_data = app.UITable.Data;
                    lgd = legend(app.UIAxes, string(lgd_data(:, 1)) + string(lgd_data(:, 2)) + string(lgd_data(:, 3)));
                    title(lgd, "Miller Indices (hkl)")
                end
    
                if app.CrystalStructureDropDown.Value == "SC"
                   SC = [0, 0, 0; 0 0 1; 0 1 0; 0 1 1; 1 0 0; 1 0 1; 1 1 0; 1 1 1];
                   SC = SC .* [a_size b_size c_size];
                   scatter3(app.UIAxes,SC(:,1), SC(:,2), SC(:,3), app.AtomicSizeSlider.Value * 1000, "red", "filled", "MarkerFaceAlpha", 0.5); 
    
                elseif app.CrystalStructureDropDown.Value == "BCC"
                   BCC = [0,0,0; 0,0,1; 0,1,0; 1,0,0; 0,1,1; 1,1,0; 1,0,1; 1,1,1; 0.5, 0.5, 0.5];
                   BCC = BCC .* [a_size b_size c_size];
                   scatter3(app.UIAxes,BCC(:,1), BCC(:,2), BCC(:,3), app.AtomicSizeSlider.Value * 1000, "red", "filled", "MarkerFaceAlpha", 0.5); 
    
                elseif app.CrystalStructureDropDown.Value == "FCC"
                   FCC =[0,0,0; 0,0,1; 0,1,0; 1,0,0; 0,1,1; 1,1,0; 1,0,1; 1,1,1; 0.5, 0.5, 0; 0.5, 0, 0.5; 0, 0.5, 0.5; 0.5, 1, 0.5; 1, 0.5, 0.5; 0.5, 0.5, 1];
                   FCC = FCC .* [a_size b_size c_size];
                   scatter3(app.UIAxes,FCC(:,1), FCC(:,2), FCC(:,3), app.AtomicSizeSlider.Value * 1000, "red", "filled", "MarkerFaceAlpha", 0.5); 
                else
                    msgbox("You can also specify a crystal structure.", "Crystal Structure", "help")
                end
                hold(app.UIAxes, "off");
            end
        end
        
        function results = samplematerials(app, selection)
            items = dictionary;
            items("Platinium Nanoparticle (Pt)") = {[1 1 1 100; 2 0 0 100; 2 2 0 100; 3 1 1 100]};
            items("Gold Nanoparticle (Au)") = {[1 1 1 100; 2 0 0 100; 2 2 0 100; 3 1 1 100]};
            items("NaCl") = {[1 1 1 100 ; 2 0 0 45; 2 2 0 100; 3 1 1 100; 2 2 2 100; 4 0 0 100; 3 3 1 100; 4 2 0 100; 4 2 2 100]};
            app.recaller(true, items(selection));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: AtomicSizeSlider
        function AtomicSizeSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Callback function
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            situation = true;
            app.recaller(situation, []);
        end

        % Value changed function: ReadPlotButton
        function ReadPlotButtonValueChanged(app, event)
            value = app.ReadPlotButton.Value;
            situation = false;
            app.recaller(situation, []);
        end

        % Value changed function: CrystalStructureDropDown
        function CrystalStructureDropDownValueChanged(app, event)
            value = app.CrystalStructureDropDown.Value;
            situation = true;
            app.recaller(situation, [])
            
        end

        % Value changed function: AtomicSizeSlider
        function AtomicSizeSliderValueChanged(app, event)
            value = app.AtomicSizeSlider.Value;
            situation = true;
            app.recaller(situation, []);
            
        end

        % Clicked callback: CrystalStructureDropDown
        function CrystalStructureDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Value changed function: PlaneOpacitySwitch
        function PlaneOpacitySwitchValueChanged(app, event)
            value = app.PlaneOpacitySwitch.Value;
            situation = true;
            app.recaller(situation, []);
        end

        % Value changed function: OpacitySlider
        function OpacitySliderValueChanged(app, event)
            value = app.OpacitySlider.Value;
            situation = true;
            app.recaller(situation, []);
        end

        % Value changed function: LatticeSizeaEditField
        function LatticeSizeaEditFieldValueChanged(app, event)
            value = app.LatticeSizeaEditField.Value;
            situation = true;
            app.recaller(situation, []);
        end

        % Value changed function: LatticeSizebEditField
        function LatticeSizebEditFieldValueChanged(app, event)
            value = app.LatticeSizebEditField.Value;
            situation = true;
            app.recaller(situation, []);
        end

        % Value changed function: LatticeSizecEditField
        function LatticeSizecEditFieldValueChanged(app, event)
            value = app.LatticeSizecEditField.Value;
            situation = true;
            app.recaller(situation, []);
        end

        % Cell edit callback: UITable
        function UITableCellEdit2(app, event)
            indices = event.Indices;
            newData = event.NewData;
            situation = true;
            app.recaller(situation, [])
        end

        % Menu selected function: AcademicModeMenu
        function AcademicModeMenuSelected(app, event)
            app.SampleMaterialsListBox.Visible=false; %Hides the Sample Materials List
            app.Label.Visible=false;
            app.CommonMaterialsLabel.Visible=false;
            app.ReadPlotButton.Visible = true;
            app.ImportRawXRDDataaswellCheckBox.Visible = true;
            cla(app.UIAxes2);
            cla(app.UIAxes);
        end

        % Menu selected function: TrainingModeMenu
        function TrainingModeMenuSelected(app, event)
            app.SampleMaterialsListBox.Visible=true; %Displays the Sample Materials List
            app.Label.Visible=true;
            app.CommonMaterialsLabel.Visible=true;
            app.ReadPlotButton.Visible = false;
            app.ImportRawXRDDataaswellCheckBox.Visible = false;
            cla(app.UIAxes2);
            cla(app.UIAxes);
        end

        % Value changed function: SampleMaterialsListBox
        function SampleMaterialsListBoxValueChanged(app, event)
            value = app.SampleMaterialsListBox.Value;
            app.samplematerials(value);
        end

        % Clicked callback: SampleMaterialsListBox
        function SampleMaterialsListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {753, 753};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {309, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.AcademicModeMenu = uimenu(app.UIFigure);
            app.AcademicModeMenu.MenuSelectedFcn = createCallbackFcn(app, @AcademicModeMenuSelected, true);
            app.AcademicModeMenu.Text = 'Academic Mode';
            app.TrainingModeMenu = uimenu(app.UIFigure);
            app.TrainingModeMenu.MenuSelectedFcn = createCallbackFcn(app, @TrainingModeMenuSelected, true);
            app.TrainingModeMenu.Text = 'Training Mode';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ReadPlotButton = uibutton(app.LeftPanel, 'state');
            app.ReadPlotButton.ValueChangedFcn = createCallbackFcn(app, @ReadPlotButtonValueChanged, true);
            app.ReadPlotButton.Text = 'Read & Plot';
            app.Image = uiimage(app.LeftPanel);
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'h'; 'k'; 'l'; 'Intensity'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit2, true);
            app.AtomicSizeSliderLabel = uilabel(app.LeftPanel);
            app.AtomicSizeSliderLabel.Text = 'Atomic Size';
            app.AtomicSizeSlider = uislider(app.LeftPanel);
            app.AtomicSizeSlider.Limits = [2 98];
            app.AtomicSizeSlider.ValueChangedFcn = createCallbackFcn(app, @AtomicSizeSliderValueChanged, true);
            app.AtomicSizeSlider.ValueChangingFcn = createCallbackFcn(app, @AtomicSizeSliderValueChanging, true);
            app.AtomicSizeSlider.Value = 2;
            app.CrystalStructureDropDownLabel = uilabel(app.LeftPanel);
            app.CrystalStructureDropDownLabel.Text = 'Crystal Structure';
            app.CrystalStructureDropDown = uidropdown(app.LeftPanel);
            app.CrystalStructureDropDown.Items = {'SC', 'BCC', 'FCC'};
            app.CrystalStructureDropDown.ValueChangedFcn = createCallbackFcn(app, @CrystalStructureDropDownValueChanged, true);
            app.CrystalStructureDropDown.ClickedFcn = createCallbackFcn(app, @CrystalStructureDropDownClicked, true);
            app.CrystalStructureDropDown.Value = 'SC';
            app.LatticeSizeaEditFieldLabel = uilabel(app.LeftPanel);
            app.LatticeSizeaEditFieldLabel.Text = 'Lattice Size(a)';
            app.LatticeSizeaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LatticeSizeaEditField.ValueChangedFcn = createCallbackFcn(app, @LatticeSizeaEditFieldValueChanged, true);
            app.LatticeSizeaEditField.Value = 1;
            app.LatticeSizebEditFieldLabel = uilabel(app.LeftPanel);
            app.LatticeSizebEditFieldLabel.Text = 'Lattice Size(b)';
            app.LatticeSizebEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LatticeSizebEditField.ValueChangedFcn = createCallbackFcn(app, @LatticeSizebEditFieldValueChanged, true);
            app.LatticeSizebEditField.Value = 1;
            app.LatticeSizecEditFieldLabel = uilabel(app.LeftPanel);
            app.LatticeSizecEditFieldLabel.Text = 'Lattice Size(c)';
            app.LatticeSizecEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LatticeSizecEditField.ValueChangedFcn = createCallbackFcn(app, @LatticeSizecEditFieldValueChanged, true);
            app.LatticeSizecEditField.Value = 1;
            app.PlaneOpacitySwitchLabel = uilabel(app.LeftPanel);
            app.PlaneOpacitySwitchLabel.Text = 'Plane Opacity';
            app.PlaneOpacitySwitch = uiswitch(app.LeftPanel, 'slider');
            app.PlaneOpacitySwitch.Items = {'Intensity', 'Slider'};
            app.PlaneOpacitySwitch.ValueChangedFcn = createCallbackFcn(app, @PlaneOpacitySwitchValueChanged, true);
            app.PlaneOpacitySwitch.Value = 'Intensity';
            app.ImportRawXRDDataaswellCheckBox = uicheckbox(app.LeftPanel);
            app.ImportRawXRDDataaswellCheckBox.Text = 'Import Raw XRD Data as well.';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image2 = uiimage(app.GridLayout2);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'bg photo.png');
            app.OpacitySliderLabel = uilabel(app.GridLayout2);
            app.OpacitySliderLabel.Text = 'Opacity';
            app.OpacitySlider = uislider(app.GridLayout2);
            app.OpacitySlider.ValueChangedFcn = createCallbackFcn(app, @OpacitySliderValueChanged, true);
            app.OpacitySlider.Value = 17.8155390703113;
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.SampleMaterialsListBox = uilistbox(app.GridLayout3);
            app.SampleMaterialsListBox.Items = {'Platinium Nanoparticle (Pt)', 'Gold Nanoparticle (Au)', 'NaCl'};
            app.SampleMaterialsListBox.ValueChangedFcn = createCallbackFcn(app, @SampleMaterialsListBoxValueChanged, true);
            app.SampleMaterialsListBox.Visible = 'off';
            app.SampleMaterialsListBox.ClickedFcn = createCallbackFcn(app, @SampleMaterialsListBoxClicked, true);
            app.SampleMaterialsListBox.Value = 'Platinium Nanoparticle (Pt)';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Visible = 'off';
            app.Label.Text = 'You can select one of the following common materials that are listed to display their cubic cell structure.';
            app.CommonMaterialsLabel = uilabel(app.GridLayout3);
            app.CommonMaterialsLabel.Visible = 'off';
            app.CommonMaterialsLabel.Text = 'Common Materials';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BoolXRD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end